classdef SmithHomework1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TimeTSlider           matlab.ui.control.Slider
        TimeTSliderLabel      matlab.ui.control.Label
        ApplyButton           matlab.ui.control.Button
        LoadImageRightButton  matlab.ui.control.Button
        LoadImageLeftButton   matlab.ui.control.Button
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % create saved spots for loaded images to apply in transition func
        image
        image2 
    end

    methods (Access = private)


        function updateImage(app, imageFile)
            % read image
            app.image = imread(imageFile);

            % display image on axes
            axis(app.UIAxes, 'off');
            imshow(app.image, 'Parent', app.UIAxes);
        end

        function updateImage2(app, imageFile)
            % read image
            app.image2 = imread(imageFile);

            % display image on axes
            axis(app.UIAxes3, 'off');
            imshow(app.image2, 'Parent', app.UIAxes3);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageLeftButton
        function LoadImageLeftButtonPushed(app, event)
            % take in user input for a file and its location
            [file, path] = uigetfile({'*.png'});

            % check if user canceled the file upload or not
            if (ischar(path))
                fileName = [path file];
                updateImage(app, fileName);
            end

        end

        % Button pushed function: LoadImageRightButton
        function LoadImageRightButtonPushed(app, event)
            % take in user input for a file and its location
            [file, path] = uigetfile({'*.png'});

            % check if user canceled the file upload or not
            if (ischar(path))
                fileName = [path file];
                updateImage2(app, fileName);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            % determine the horizontal location for Xt in slide
            % transition boundary
            % Xt = (t/tmax)*Xmax
            %Tmax = 6, Xmax = 768; given
            %max time
            Tmax = 6;
            %max x-position in viewport
            Xmax = 768;
            % current time from slider value
            t = app.TimeTSlider.Value;
            % set current position on viewport, make sure not equal to zero
            Xt = max(1,(t/Tmax)*Xmax);

            % make new image to manipulate
            slideDisplay = app.image2;

            % create left side of image
            leftImg = app.image;

            % create right side of image
            rightImg = app.image2;

            % apply the necessary columns of left image to output image
            % set the end of the left image to the end of the slideDisplay
            slideDisplay(:, 1:Xt,:) = leftImg(:, (Xmax-Xt+1):end,:);

            % apply the necessary columns of right image to output image
            % set the end of the right image to the end of the slideDisplay
            slideDisplay(:, Xt:Xmax,:) = rightImg(:,1:(Xmax-Xt+1), :);
            
            % display new transition image
            imshow(slideDisplay, 'Parent', app.UIAxes2);

            % save image to memory
            imwrite(slideDisplay, "sliderImage.png");


        end

        % Callback function
        function TimeTSliderValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Left')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Viewport')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image Right')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.LoadImageLeftButton = uibutton(app.UIFigure, 'push');
            app.LoadImageLeftButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageLeftButtonPushed, true);
            app.LoadImageLeftButton.Text = 'Load Image Left';
            app.LoadImageRightButton = uibutton(app.UIFigure, 'push');
            app.LoadImageRightButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageRightButtonPushed, true);
            app.LoadImageRightButton.Text = 'Load Image Right';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TimeTSliderLabel = uilabel(app.UIFigure);
            app.TimeTSliderLabel.Text = 'Time T';
            app.TimeTSlider = uislider(app.UIFigure);
            app.TimeTSlider.Limits = [0 6];
            app.TimeTSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmithHomework1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end