classdef subsampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SubsamplingFormatButtonGroup  matlab.ui.container.ButtonGroup
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        ApplySubsamplingButton        matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        ImageGray                     matlab.ui.control.UIAxes
        ImageRGB                      matlab.ui.control.UIAxes
        ImageOriginal                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplySubsamplingButton
        function apply(app, event)
            % See which subsampling option is selected
            config = false;
            if (app.Button.Value == true)
                % subsampling 4:2:2 chosen
                % call function and apply
                config = true;
            end
               
            % Call the function that handles subsampling
            [RGB, Gray] = subsample(app.ImageOriginal.UserData, config);
                
            % display the RGB image on app
            % rgb = imread(RGB);
            axis(app.ImageRGB, 'off');
            set(app.ImageRGB, 'UserData', RGB);
            imshow(RGB, 'Parent', app.ImageRGB);
                
            % display the grayscale image on app
            % gray = imread(Gray);
            axis(app.ImageGray, 'off');
            set(app.ImageGray, 'UserData', Gray);
            imshow(Gray, 'Parent', app.ImageGray);            
        end

        % Button pushed function: LoadImageButton
        function loadImage(app, event)
            [f, p] = uigetfile({'*.jpg; *.png; *.gif', 'All Image Files'});
            if (ischar(p))
                fname = [p f];
                im = imread(fname);
                axis(app.ImageOriginal, 'off');
                set(app.ImageOriginal, 'UserData', im);
                imshow(im, 'Parent', app.ImageOriginal);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageOriginal = uiaxes(app.UIFigure);
            title(app.ImageOriginal, 'Original Image')
            app.ImageOriginal.XTick = [];
            app.ImageOriginal.YTick = [];
            app.ImageRGB = uiaxes(app.UIFigure);
            title(app.ImageRGB, 'RGB Subsampling')
            app.ImageRGB.XTick = [];
            app.ImageRGB.YTick = [];
            app.ImageGray = uiaxes(app.UIFigure);
            title(app.ImageGray, 'Grayscale Subsampling')
            app.ImageGray.XTick = [];
            app.ImageGray.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ApplySubsamplingButton = uibutton(app.UIFigure, 'push');
            app.ApplySubsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplySubsamplingButton.Text = 'Apply Subsampling';
            app.SubsamplingFormatButtonGroup = uibuttongroup(app.UIFigure);
            app.SubsamplingFormatButtonGroup.Title = 'Subsampling Format';
            app.Button = uiradiobutton(app.SubsamplingFormatButtonGroup);
            app.Button.Text = '4:2:2';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SubsamplingFormatButtonGroup);
            app.Button_2.Text = '4:2:0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subsampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end